classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Panel_4                      matlab.ui.container.Panel
        MULTIFEATURECBIRSYSTEMLabel  matlab.ui.control.Label
        Panel_3                      matlab.ui.container.Panel
        TOPRESULTSLabel              matlab.ui.control.Label
        QUERYIMAGELabel              matlab.ui.control.Label
        Panel_2                      matlab.ui.container.Panel
        Image_22                     matlab.ui.control.Image
        Rank10Label                  matlab.ui.control.Label
        Image_21                     matlab.ui.control.Image
        Rank5Label                   matlab.ui.control.Label
        Image_20                     matlab.ui.control.Image
        Rank9Label                   matlab.ui.control.Label
        Image_19                     matlab.ui.control.Image
        Rank4Label                   matlab.ui.control.Label
        Image_18                     matlab.ui.control.Image
        Rank6Label                   matlab.ui.control.Label
        Image_17                     matlab.ui.control.Image
        Rank1Label                   matlab.ui.control.Label
        Image_16                     matlab.ui.control.Image
        Rank7Label                   matlab.ui.control.Label
        Image_15                     matlab.ui.control.Image
        Rank2Label                   matlab.ui.control.Label
        Image_14                     matlab.ui.control.Image
        Rank8Label                   matlab.ui.control.Label
        Image_13                     matlab.ui.control.Image
        Rank3Label                   matlab.ui.control.Label
        Image_12                     matlab.ui.control.Image
        Panel                        matlab.ui.container.Panel
        SaveRecommendationButton     matlab.ui.control.Button
        UpdateDatabaseButton         matlab.ui.control.Button
        ClearButton                  matlab.ui.control.Button
        SearchButton                 matlab.ui.control.Button
        LoadImageButton              matlab.ui.control.Button
    end

        properties (Access = public)
        QueryImage              % Matrix of the picked image
        QueryImageName          % To store the filename for saving (e.g., "409")
        RecommendedFilePath     % Full path to the Rank 1 image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            [file, path] = uigetfile({'*.jpg;*.png;*.bmp'});
            if isequal(file, 0), return; end

            app.QueryImage = imread(fullfile(path, file));
            app.Image_22.ImageSource = app.QueryImage; 
            [~, app.QueryImageName, ~] = fileparts(file); 

            % Re-ordered resBoxes to match Rank 1-10 positions
            resBoxes = {app.Image_16, app.Image_14, app.Image_12, app.Image_18, app.Image_20, ...
                        app.Image_17, app.Image_15, app.Image_13, app.Image_19, app.Image_21};
                
            for k = 1:10
                resBoxes{k}.ImageSource = ''; 
            end

            app.LoadImageButton.Enable = 'off';
            app.SearchButton.Enable = 'on';
            app.ClearButton.Enable = 'on';
            app.SaveRecommendationButton.Enable = 'off';
        end

        % Button pushed function: SearchButton
        function SearchButtonPushed(app, event)
            fRaw = featureVector(app.QueryImage); 
    
            if ~exist('fvect.mat', 'file')
                uialert(app.UIFigure, 'Please Update Database first!', 'Missing fvect.mat');
               return;
            end
    
            data = load('fvect.mat');
            fQuery = (fRaw - data.f_min) ./ (data.f_max - data.f_min + eps);
    
            imgpath = 'image.orig';
            imgFiles = dir(fullfile(imgpath, '*.jpg'));
            validFiles = imgFiles(~startsWith({imgFiles.name}, '.'));
    
            dists = zeros(length(validFiles), 1);
            for j = 1:length(validFiles)
                dists(j) = euclid(fQuery, data.fvect(j, :)); 
            end
    
            [sortedDists, idx] = sort(dists, 'ascend');
            app.RecommendedFilePath = fullfile(imgpath, validFiles(idx(1)).name);
    
            % Corrected Mapping: 16=Rank1, 14=Rank2, 12=Rank3, etc.
            resBoxes = {app.Image_16, app.Image_14, app.Image_12, app.Image_18, app.Image_20, ...
                        app.Image_17, app.Image_15, app.Image_13, app.Image_19, app.Image_21};
            resLabels = {app.Rank1Label, app.Rank2Label, app.Rank3Label, app.Rank4Label, app.Rank5Label, ...
                        app.Rank6Label, app.Rank7Label, app.Rank8Label, app.Rank9Label, app.Rank10Label};            

            for k = 1:10
                resBoxes{k}.ImageSource = fullfile(imgpath, validFiles(idx(k)).name);
                resLabels{k}.Text = sprintf('Rank %d (Dist: %.4f)', k, sortedDists(k));
            end
    
            app.SaveRecommendationButton.Enable = 'on';
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.Image_22.ImageSource = ''; 
    
            resBoxes = {app.Image_16, app.Image_14, app.Image_12, app.Image_18, app.Image_20, ...
                        app.Image_17, app.Image_15, app.Image_13, app.Image_19, app.Image_21};
            for k = 1:10
                resBoxes{k}.ImageSource = '';
            end

            app.QueryImage = [];
            app.LoadImageButton.Enable = 'on';
            app.SearchButton.Enable = 'off';
            app.ClearButton.Enable = 'off';
            app.SaveRecommendationButton.Enable = 'off';
        end

        % Button pushed function: UpdateDatabaseButton
        function UpdateDatabaseButtonPushed(app, event)
            imgpath = 'image.orig'; 
            imglist = dir(fullfile(imgpath, '*.jpg')); 
            validFiles = imglist(~startsWith({imglist.name}, '.'));
            numFiles = length(validFiles);

            if numFiles == 0
                uialert(app.UIFigure, 'No images found in "image.orig"!', 'Folder Error', 'Icon', 'error');
                return;
            end

            prog = uiprogressdlg(app.UIFigure, 'Title', 'Feature Extraction', ...
                'Message', 'Initializing analysis...', 'Cancelable', 'off');
    
            raw_fvect = [];
            for i = 1:numFiles
                prog.Value = i/numFiles; 
                prog.Message = sprintf('Loading Image %d/%d (%.0f%%)', i, numFiles, (i/numFiles)*100);
        
                imgname = fullfile(imgpath, validFiles(i).name);
                img = imread(imgname);
                raw_fvect(i, :) = featureVector(img); 
            end

            % Normalization logic to prevent scaling issues
            f_min = min(raw_fvect);
            f_max = max(raw_fvect);
            diff = f_max - f_min;
            diff(diff == 0) = 1; 
            fvect = (raw_fvect - f_min) ./ diff;

            save('fvect.mat', 'fvect', 'f_min', 'f_max');
            close(prog);
            uialert(app.UIFigure, 'Database Updated! You can now Search.', 'Success', 'Icon', 'success');
        end

        % Button pushed function: SaveRecommendationButton
        function SaveRecommendationButtonPushed(app, event)
            % 1. Create the confirmation message with the image preview
            msg = sprintf('Do you want to save the Rank 1 recommendation as "%s_extracted.jpg"?', app.QueryImageName);
    
            % 2. Open the Confirmation Window
            % 'Options' creates your custom buttons
            % 'DefaultOption' sets which one is highlighted
            selection = uiconfirm(app.UIFigure, msg, 'Confirm Save', ...
                'Options', {'Save', 'Cancel'}, ...
                'DefaultOption', 'Save', ...
                'Icon', app.RecommendedFilePath); % This displays the Rank 1 image as the icon!

            % 3. Handle the User Selection
                switch selection
            case 'Save'
                destFolder = 'img_ext';
                if ~exist(destFolder, 'dir'), mkdir(destFolder); end

                newName = [app.QueryImageName '_extracted.jpg'];
                destPath = fullfile(destFolder, newName);

                try
                    copyfile(app.RecommendedFilePath, destPath);
                    uialert(app.UIFigure, 'Image successfully saved to img_ext!', 'Success', 'Icon', 'success');
                    app.SaveRecommendationButton.Enable = 'off'; % Disable after successful save
                catch
                    uialert(app.UIFigure, 'Could not save the file.', 'Error', 'Icon', 'error');
                end
            
            case 'Cancel'
                % Do nothing, just close the window
                return;
                end
            app.SaveRecommendationButton.Enable = 'off'; % Logic for Step 4
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.SearchButton = uibutton(app.UIFigure, 'push');
            app.SearchButton.ButtonPushedFcn = createCallbackFcn(app, @SearchButtonPushed, true);
            app.SearchButton.Text = 'Search';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.UpdateDatabaseButton = uibutton(app.UIFigure, 'push');
            app.UpdateDatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateDatabaseButtonPushed, true);
            app.UpdateDatabaseButton.Text = 'Update Database';
            app.SaveRecommendationButton = uibutton(app.UIFigure, 'push');
            app.SaveRecommendationButton.ButtonPushedFcn = createCallbackFcn(app, @SaveRecommendationButtonPushed, true);
            app.SaveRecommendationButton.Text = 'Save Recommendation';
            app.Panel = uipanel(app.UIFigure);
            app.Image_12 = uiimage(app.UIFigure);
            app.Rank3Label = uilabel(app.UIFigure);
            app.Rank3Label.Text = 'Rank 3';
            app.Image_13 = uiimage(app.UIFigure);
            app.Rank8Label = uilabel(app.UIFigure);
            app.Rank8Label.Text = 'Rank 8';
            app.Image_14 = uiimage(app.UIFigure);
            app.Rank2Label = uilabel(app.UIFigure);
            app.Rank2Label.Text = 'Rank 2';
            app.Image_15 = uiimage(app.UIFigure);
            app.Rank7Label = uilabel(app.UIFigure);
            app.Rank7Label.Text = 'Rank 7';
            app.Image_16 = uiimage(app.UIFigure);
            app.Rank1Label = uilabel(app.UIFigure);
            app.Rank1Label.Text = 'Rank 1';
            app.Image_17 = uiimage(app.UIFigure);
            app.Rank6Label = uilabel(app.UIFigure);
            app.Rank6Label.Text = 'Rank 6';
            app.Image_18 = uiimage(app.UIFigure);
            app.Rank4Label = uilabel(app.UIFigure);
            app.Rank4Label.Text = 'Rank 4';
            app.Image_19 = uiimage(app.UIFigure);
            app.Rank9Label = uilabel(app.UIFigure);
            app.Rank9Label.Text = 'Rank 9';
            app.Image_20 = uiimage(app.UIFigure);
            app.Rank5Label = uilabel(app.UIFigure);
            app.Rank5Label.Text = 'Rank 5';
            app.Image_21 = uiimage(app.UIFigure);
            app.Rank10Label = uilabel(app.UIFigure);
            app.Rank10Label.Text = 'Rank 10';
            app.Panel_2 = uipanel(app.UIFigure);
            app.Image_22 = uiimage(app.Panel_2);
            app.QUERYIMAGELabel = uilabel(app.UIFigure);
            app.QUERYIMAGELabel.Text = 'QUERY IMAGE';
            app.Panel_3 = uipanel(app.UIFigure);
            app.TOPRESULTSLabel = uilabel(app.Panel_3);
            app.TOPRESULTSLabel.Text = 'TOP RESULTS';
            app.Panel_4 = uipanel(app.UIFigure);
            app.MULTIFEATURECBIRSYSTEMLabel = uilabel(app.Panel_4);
            app.MULTIFEATURECBIRSYSTEMLabel.Text = 'MULTI-FEATURE CBIR SYSTEM';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end